classdef v1_SerialTerminalAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Terminal                       matlab.ui.Figure
        Image2_3                       matlab.ui.control.Image
        Image2_2                       matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ADuCM3029SerialInterfaceLabel  matlab.ui.control.Label
        SendPanel                      matlab.ui.container.Panel
        SendButton                     matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        SerialConnectionPanel          matlab.ui.container.Panel
        ClearButton                    matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        PortDropDown                   matlab.ui.control.DropDown
        PortLabel                      matlab.ui.control.Label
        TerminalPanel                  matlab.ui.container.Panel
        SentdataTextArea               matlab.ui.control.TextArea
        SentdataTextAreaLabel          matlab.ui.control.Label
        ReceiveddataTextArea           matlab.ui.control.TextArea
        ReceiveddataTextAreaLabel      matlab.ui.control.Label
    end

   properties (Access = private)
        %timers
        generic_upd_tmr = timer;
        %data
        port_list;
        sent_log;
        rsvd_log;
        %vars
        current_port = '';
        %sem
        port_fxn_pass = false;
        con_in_process = false;
    end 


    methods (Access = private)
        
        %%% GUI update Function
        function  form_upd(app)
            switch app.con_state("left",false)
                case 'Connected'
                     if(app.current_port.BytesAvailable)
                       app.rsvd_log{end+1} = fscanf(app.current_port); 
                       app.rsvd_log{end} = app.rsvd_log{end}(1:end-1);
                       app.ReceiveddataTextArea.Value = app.rsvd_log;
                     end
                case 'Disconnected'
                   %portlist scan 
                   s_info = instrhwinfo('serial');
                   app.port_list =  s_info.AvailableSerialPorts;
                   %set COM port in the GUI
                   app.PortDropDown.Items = app.port_list;
                   app.PortDropDown.ItemsData = app.port_list;
            end
        end
        
        %%% Connection function
        function connect_fxn(app)
                   %% port connection module
                    try
                        app.con_in_process=true;
                        app.current_port = serial(app.PortDropDown.Value);
                        fopen(app.current_port);
                        app.port_fxn_pass = true;
                    catch ME
                        if  (ME.identifier == "MATLAB:serial:fopen:opfailed")
                            warning('MATLAB_serial_fopen_opfailed');                   
                        else
                             warning('Connection_failen_unknown_reason');
                        end 
                        app.port_fxn_pass=false;
                    end
                        app.con_in_process = false;
                        
                    %% end of port connection module
                        %Serial settings and GUI update
                    if (app.con_state("left",false) == "Connected")
                        set(app.current_port,'BaudRate',app.BaudrateDropDown.Value);
                        set(app.current_port,'FlowControl','Software');
                        app.current_port.Terminator='CR';
                        app.ConnectButton.Text = 'Disconnect';
                        app.PortDropDown.Enable = 'off';
                        app.BaudrateDropDown.Enable = 'off';
                        app.SendButton.Enable = 'on';
                    end
                end
        
        %%% Disconnect Function        
        function disconnect_fxn(app)
            if  app.con_state("left",false) == "Connected"
                app.ConnectButton.Text = 'connect';
                app.con_state("set",false);
                try
                    fclose(app.current_port);
                catch 
                    warning('Disconnect_failed');
                end
                app.current_port = '';
                app.PortDropDown.Enable = 'on';
                app.BaudrateDropDown.Enable = 'on';
                app.SendButton.Enable = 'off';
                stop(app.generic_upd_tmr);
            end
        end
        
        %%% State Function
        function state = con_state(app,set,val)
            state = "Disconnected";
            if (app.port_fxn_pass && ~app.con_in_process)
                state = "Connected";
            end
            if (app.port_fxn_pass && app.con_in_process)
                state = "Connecting";
            end
            if (set == "set")
                app.port_fxn_pass = val;
                app.con_in_process = val;
            end
      end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           % Create gui_timer that each period execute the form_upd() fnc
           app.generic_upd_tmr.Period = 0.1;
           app.generic_upd_tmr.ExecutionMode = 'fixedRate';
           app.generic_upd_tmr.TimerFcn = @(~, ~)app.form_upd;
           
           % Baudrate drop-down
           app.BaudrateDropDown.Items = {'9600','38400','115200','230400'};
           app.BaudrateDropDown.ItemsData = {9600, 38400, 115200, 230400};
           
           % Update GUI
           app.form_upd();
           
           start(app.generic_upd_tmr);
        end

        % Close request function: Terminal
        function TerminalCloseRequest(app, event)
            app.disconnect_fxn();
            delete(app)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            % when the button is pushed check first if COM is set
            if(~isempty(app.PortDropDown.Value))
                switch app.con_state("left",false)
                    case "Connected"
                        app.disconnect_fxn();
                    case "Disconnected"
                        app.connect_fxn();
                end
            end
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            send_ok = false;
            
            if((app.con_state("left",false)== "Connected") && (~isempty(app.EditField.Value)))
                try
                    % Sending data through Serial Port with fprintf()
                    % resaults on a loss of alternating characters perhaps
                    % becouse the fprintf() sends string but the MC
                    % expect a vector of characters. To get around the
                    % problem the string is manipulated by adding a space
                    % between each character
                        mess=app.EditField.Value;
                        mess1=convertStringsToChars(mess); 
                        len=strlength(mess1);
                        for i=1:len
                             if i == 1
                                    temp = mess1(1);
                             elseif i == len
                                    temp = [temp ' ' mess1(i) ' '];
                             else
                                    temp = [temp ' ' mess1(i)];
                             end
                        end
                     stringa=convertCharsToStrings(temp);
                    
                    
                    fprintf(app.current_port,stringa,"async");
                    send_ok = true;
                catch 
                    warning('Send_failed');                   
                    send_ok = false;
                end
            end
            if (send_ok)
                app.sent_log{end+1}=app.EditField.Value;
                app.SentdataTextArea.Value=app.sent_log;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %Disconnect and clear text areas
            app.disconnect_fxn();
            delete(instrfind);
            app.ReceiveddataTextArea.Value=' '; 
            app.EditField.Value=' ';
            app.SentdataTextArea.Value=' ';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Terminal = uifigure('Visible', 'off');
            app.Terminal.Name = 'UI Figure';
            app.Terminal.CloseRequestFcn = createCallbackFcn(app, @TerminalCloseRequest, true);
            app.TerminalPanel = uipanel(app.Terminal);
            app.TerminalPanel.Title = 'Terminal';
            app.ReceiveddataTextAreaLabel = uilabel(app.TerminalPanel);
            app.ReceiveddataTextAreaLabel.Text = 'Received data';
            app.ReceiveddataTextArea = uitextarea(app.TerminalPanel);
            app.SentdataTextAreaLabel = uilabel(app.TerminalPanel);
            app.SentdataTextAreaLabel.Text = 'Sent data';
            app.SentdataTextArea = uitextarea(app.TerminalPanel);
            app.SerialConnectionPanel = uipanel(app.Terminal);
            app.SerialConnectionPanel.Title = 'Serial Connection';
            app.PortLabel = uilabel(app.SerialConnectionPanel);
            app.PortLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.SerialConnectionPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.SerialConnectionPanel);
            app.BaudrateDropDownLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.SerialConnectionPanel);
            app.BaudrateDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.BaudrateDropDown.Value = '';
            app.ConnectButton = uibutton(app.SerialConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ClearButton = uibutton(app.SerialConnectionPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SendPanel = uipanel(app.Terminal);
            app.SendPanel.Title = 'Send';
            app.EditField = uieditfield(app.SendPanel, 'text');
            app.SendButton = uibutton(app.SendPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.ADuCM3029SerialInterfaceLabel = uilabel(app.Terminal);
            app.ADuCM3029SerialInterfaceLabel.Text = 'ADuCM3029 Serial Interface';
            app.Image = uiimage(app.Terminal);
            app.Image.ImageSource = 'Logo appetta.png';
            app.Image2 = uiimage(app.Terminal);
            app.Image2.ImageSource = 'Bianco.png';
            app.Image2_2 = uiimage(app.Terminal);
            app.Image2_2.ImageSource = 'Bianco.png';
            app.Image2_3 = uiimage(app.Terminal);
            app.Image2_3.ImageSource = 'Bianco.png';
            % Show the figure after all components are created
            app.Terminal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v1_SerialTerminalAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Terminal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Terminal)
        end
    end
end