classdef Copy_of_BIA_F < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Terminal                        matlab.ui.Figure
        FrancOlinoTeamLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        Insulinmeter20Label_2           matlab.ui.control.Label
        WelcometoLabel                  matlab.ui.control.Label
        SerialTab                       matlab.ui.container.Tab
        SerialConnectionPanel           matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.Button
        BaudrateDropDown                matlab.ui.control.DropDown
        BaudrateDropDownLabel           matlab.ui.control.Label
        PortDropDown                    matlab.ui.control.DropDown
        PortLabel                       matlab.ui.control.Label
        BluetoothTab                    matlab.ui.container.Tab
        BluetoothConnectionPanel        matlab.ui.container.Panel
        DisconnectButton                matlab.ui.control.Button
        ConnectButton_2                 matlab.ui.control.Button
        ScanButton                      matlab.ui.control.Button
        BluetoothDevicesDropDown        matlab.ui.control.DropDown
        BluetoothDevicesDropDownLabel   matlab.ui.control.Label
        FrequencySettingsPanel          matlab.ui.container.Panel
        SweepSwitch                     matlab.ui.control.Switch
        SweepSwitchLabel                matlab.ui.control.Label
        FrequencySweepPanel             matlab.ui.container.Panel
        SweepCyclesEditField            matlab.ui.control.EditField
        SweepCyclesEditFieldLabel       matlab.ui.control.Label
        SweepPointsEditField            matlab.ui.control.EditField
        SweepPointsEditFieldLabel       matlab.ui.control.Label
        StopFrequencyEditField          matlab.ui.control.EditField
        StopFrequencyEditFieldLabel     matlab.ui.control.Label
        StartFrequencyEditField_2       matlab.ui.control.EditField
        StartFrequencyEditField_2Label  matlab.ui.control.Label
        SingleFrequencyPanel            matlab.ui.container.Panel
        FrequencyEditField              matlab.ui.control.EditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Insulinmeter20Label             matlab.ui.control.Label
        ConsoleLogPanel                 matlab.ui.container.Panel
        ReceiveddataTextArea            matlab.ui.control.TextArea
        MarkersPanel                    matlab.ui.container.Panel
        AddMarkerButton                 matlab.ui.control.Button
        MarkernameButton                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        UITable2                        matlab.ui.control.Table
        CommandsPanel                   matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        SavedataButton                  matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        PlotFullDataButton              matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

% Class App_BIA_BLE includes methods, functions, properties and variables
% for each GUI component (Buttons, panels...) there is a public property


    %%% Versione 5.0 BIA Serial/Bluetooth   


    %% Global Proprieties/variable defined by the User and accessible from all functions    
    properties (Access = private)
        %COM Port
        port_list;
        rsvd_log;
        current_port = '';
        %Sem
        port_fxn_pass = false;
        con_in_process = false;
        
        %Plot Variables
        time            %Sample vector
        mag             %Magnitude vector
        mag_1
        phase           %Phase vector
        phase_1
        freq            %Frequence vector
        freq_1
        sample_period 
        data
        data_mag        %latest magnitude data
        data_phase      %latest phase data
        data_freq       %latest frequency data
        %Mean and Std vectors
        mean_mag    
        mean_phase
        std_mag
        std_phase
        data_Meantable
        
        %Bluetooth varibles
        b               %BLE device
        car             %Read charachteristic
        car2            %Write characteristic

        %Data visualization variables
        temp
        marker          %Marker lable vector
        count
        peek_saved
        time_saved
        data_table
        moltM           %Magnitude multiplier for axes
        moltP           %Phase multiplier for axes
        first_time
        update_freq
        CountPunti      %Sweep points counter
        N_Punti         %Sweep point for each cycle 
        N_Cicli         %Sweep cycles 
        cont_usedata    
        PeakType
        
        %Axis limits
        m1
        m2
        p1
        p2
        
        %BIA settings
        freqa           %bia frequency
        freq1           %bia start frequency
        freq2           %bia stop frequency
        nPunti          %bia sweep points
        nCicli          %bia sweep cycles
        
        clear_to_send
        
        %markers struct 
        marker_table
        marker_temp = struct('N',0,'Lable', " ",'Freq',0, 'Mag', 0, 'Phase', 0, 'Sample', 0);
        marker_saved = struct('N',0,'Lable'," ",'Freq',0, 'Mag', 0, 'Phase', 0, 'Sample', 0);
        lable_cnt
        couples
        
        
        % Current time
        current_time
   end 


   %%% User Methods, all the functions defined by the User
   methods (Access = private)


       %% GUI Update Function - called on app startup
       %% If App state is disconnected scans Serial Port available
       function  form_upd(app)
           if  app.con_state("left",false) == "Disconnected"
               % Scan portlist
               s_info = instrhwinfo('serial');
               app.port_list =  s_info.AvailableSerialPorts;
               % Update GUI elements
               app.PortDropDown.Items = app.port_list;
               app.PortDropDown.ItemsData = app.port_list;
               app.AddMarkerButton.Enable = 'off';
               app.StartButton.Enable = 'off';
               app.StopButton.Enable = 'off';
               app.SavedataButton.Enable = 'off';
               app.PlotFullDataButton.Enable = 'off';
               app.MarkernameButton.Enable = 'off';
               app.SingleFrequencyPanel.Enable = 'on';
               app.FrequencySweepPanel.Enable = 'off';
               app.ClearButton.Enable = 'off';
               ylabel(app.UIAxes, 'Impedance Magnitude [\Omega]')
               xtickformat(app.UIAxes_2,'%.f');
               xtickformat(app.UIAxes,'%.f');
               ytickformat(app.UIAxes_2,'%.2f');
               ytickformat(app.UIAxes,'%.1f');
               app.ResetButton.Enable = 'off';
               app.SerialTab.Parent = [];
               app.BluetoothTab.Parent = [];

           end
       end


       %% Serial Connection Function
       %% Open Serial Port and initialize all the varibles
       function connect_fxn(app)

           %% port connection module
           try
               app.con_in_process=true;
               app.current_port = serial(app.PortDropDown.Value);
               fopen(app.current_port);
               app.port_fxn_pass = true;
               app.Switch.Enable = 'off';            %connection type switch

           catch
               app.ReceiveddataTextArea.Value = "Connection failed!";


               app.ReceiveddataTextArea.Value = "Connected";
               app.port_fxn_pass=false;
           end
           app.con_in_process = false;

           % Serial settings, GUI update and variables initialize
           if (app.con_state("left",false) == "Connected")
               set(app.current_port,'BaudRate',app.BaudrateDropDown.Value);
               set(app.current_port,'FlowControl','Software');

               app.ConnectButton.Text = 'Disconnect';
               app.StartButton.Enable = 'on';
               app.PortDropDown.Enable = 'off';
               app.BaudrateDropDown.Enable = 'off';
               app.time = [];
               app.mag = [];
               app.marker = [];
               app.phase = [];
               app.freq = [];
               app.mag_1 = [];
               app.phase_1 = [];
               app.freq_1 = [];
               app.count = 0;
               app.marker_saved = [];
               app.marker_saved(1).N = 0;
               app.marker_saved(1).Lable = " ";
               app.marker_saved(1).Freq = 0;
               app.marker_saved(1).Mag = 0;
               app.marker_saved(1).Phase = 0;
               app.marker_saved(1).Sample = 0;
               app.couples = [];
               app.CountPunti = 0;
               app.mean_mag = [];
               app.mean_phase = [];
               app.lable_cnt = 1; %try 1 or 0
               app.sample_period = 1;  % In this case 1 in betters
               app.current_time = []; % current time inizialize
               app.temp = "1000 208,89732 356212 \r\n";
               
           end
       end


       %% Use Data Function - read and store datas received from serial port and calls process_data()
       function use_data(app,src,event)


           app.temp = fscanf(app.current_port);
           Elemento = split(app.temp);
           % convert received string in double
           app.data_freq=str2double(char(Elemento(1)));
           app.data_mag=str2double(char(Elemento(2)));
           app.data_phase=str2double(char(Elemento(3)));
           %% questo probabilmente va prima di entrare nella funzione process_data perché non viene cancellato bene il grafico
           if(mod(app.CountPunti,app.N_Punti) == 0)
               cla(app.UIAxes);
               cla(app.UIAxes_2);
               app.mag_1 = [];
               app.phase_1 = [];
               app.freq_1 = [];
           end
           if(app.cont_usedata ~=0 || strcmpi(app.SweepSwitch.Value,'On')==1)
                app.process_data();
           else
               app.cont_usedata = 1;
           end


       end

       %% Current time Update
       %% Take the hour, minutes anche seconds from Matlab 'clock' and create a string vector
       function current_time_update(app)
           t = clock; % matlab function to acquire the time
           fix(t); % fix the values
           hours = t(4); % the fourth 't' Value is the Hour
           minutes = t(5); % the fifth 't' Value are the minutes
           seconds = fix(t(6)); % the sixth 't' Value are the seconds
           t2 = num2str(hours)+":"+num2str(minutes)+":"+num2str(seconds); % String creation with hours, minutes and seconds

           app.current_time = [app.current_time t2]; % add the new string to the vector
       end


       %% Disconnect Function
       %% Closes serial port and enable buttons
       function disconnect_fxn(app)
           if  app.con_state("left",false) == "Connected"
               app.ConnectButton.Text = 'Connect';
               app.con_state("set",false);
               try
                   fclose(app.current_port);
               catch
                   app.ReceiveddataTextArea.Value = "Disconnection failed!";
               end
               app.ReceiveddataTextArea.Value = "Disconnected";
               app.current_port = '';
               app.PortDropDown.Enable = 'on';
               app.BaudrateDropDown.Enable = 'on';
               app.AddMarkerButton.Enable = 'off';
               app.StartButton.Enable = 'off';
               app.StopButton.Enable = 'off';
               app.MarkernameButton.Enable = 'off';
               app.FrequencySettingsPanel.Enable = 'on';
               app.Switch.Enable = 'off';
           end
       end


       %% Save to file Function
       %% Write data_table on a file
       function save_file(app)
           try
               ok={'File salvato correttamente','FileName -> Salvataggio.txt'};

               % Ask base file name and path
               [FileName, FilePath] = uiputfile('*.txt');
               % Create the full path and compound files names
               data_file = [FilePath 'Data_' FileName];
               marker_file = [FilePath 'Marker_' FileName];


               %Write table on specified file
               writetable(app.data_table,data_file,'Delimiter', 'tab', 'WriteMode','Append');
               writetable(app.marker_table,marker_file,'Delimiter', 'tab', 'WriteMode','Append');

               %Pop up alert
               uialert(app.Terminal,ok,'Success','Icon','success');

           catch
               %Pop up alert
               No={'Errore nel salvataggio'};
               uialert(app.Terminal,No,'Error');
           end

           if(isempty(app.mean_mag) == 0)
               Mean_file =  [FilePath 'MeanStd_' FileName];
               writetable(app.data_Meantable,Mean_file,'Delimiter', 'tab', 'WriteMode','Append');
           end

       end


       %% State Function
       %% Checks app state
       function state = con_state(app,set,val)
           state = "Disconnected";
           if (app.port_fxn_pass && ~app.con_in_process)
               state = "Connected";
           end
           if (app.port_fxn_pass && app.con_in_process)
               state = "Connecting";
           end
           if (set == "set")
               app.port_fxn_pass = val;
               app.con_in_process = val;
           end
       end


       %% Marker Function
       %% Sets a Marker whitout Lable that will be set later on
       function set_marker(app)


           app.marker_temp.N = app.marker_saved(end).N+1;
           app.marker_temp.Freq = app.freq(end);
           app.marker_temp.Mag = app.mag(end);
           app.marker_temp.Phase = app.phase(end);
           app.marker_temp.Sample = app.time(end);
           %app.merker_temp.Lable = app.;

           app.marker_saved = [app.marker_saved app.marker_temp];

           % Delete first empty row
           if(app.marker_saved(1).N == 0)
               app.marker_saved(1) = [];
           end

           % convert marker_saved in table
           app.create_marker_table();

           % update marker table component in the GUI
           app.UITable2.Data = app.marker_table;
       end


       %% Create Marker Table Function
       %% Converts marker_saved in table
       function create_marker_table(app)
           app.marker_table = struct2table(app.marker_saved);
       end


       %% Adds Lable to a marker
       function add_lable(app)
           
           while ( (app.lable_cnt <= app.marker_saved(end).N) && app.marker_saved(app.lable_cnt).Lable == "Peak")
               app.lable_cnt = app.lable_cnt + 1;
           end
           if ( app.lable_cnt <= app.marker_saved(end).N && app.marker_saved(app.lable_cnt).Lable ~= "Peak")
               if((app.lable_cnt <= app.marker_saved(end).N) && (isempty(app.EditField) == 0) )%checks if there are markers to modify and there are somthing in the Edit Field
                   lbl= app.EditField.Value;
                   app.marker_saved(app.lable_cnt).Lable = lbl;

                   % convert new marker_saved in table
                   app.create_marker_table();

                   % update marker table component in the GUI
                   app.UITable2.Data = app.marker_table;
               end
           end
           app.lable_cnt = app.lable_cnt + 1;
           app.EditField.Value = '';
       end


       %% Create Data Table Function
       %% Converts all the received data in table
       function create_data_table(app)
           Magnitude=transpose(app.mag);
           Frequency=transpose(app.freq);
           Phase=transpose(app.phase);
           Sample=transpose(app.time);
           Marker=transpose(app.marker);
           Timestamp=transpose(app.current_time);
           app.data_table=table(Sample,Frequency,Magnitude,Phase,Marker,Timestamp);
       end


       %% Plot full data, two cases
       %% 1. Signle Frequency: Highlight areas between markers  
       %% 2. Frequncy Sweep: calculate and plot mean and std vectors
       function full_data_plot(app)
           cla(app.UIAxes);
           cla(app.UIAxes_2);
           
           for i=1:app.marker_saved(end).N
               temp = app.marker_saved(i).Sample;
               app.marker(temp+1)= app.marker_saved(i).Lable;
           end
           app.create_data_table();
           app.UITable.Data = app.data_table;
           scroll(app.UITable, 'bottom'); % autoscroll Table
           
           
           if(strcmpi(app.SweepSwitch.Value,'Off')==1)
               %% Single Frequency
               % clears existing graphics and plot the full received data set
               if length(app.time) > 1

                   plot(app.UIAxes, app.time, app.mag,'k');
                   axis(app.UIAxes,[0 app.time(end) min(app.mag)-app.moltM*min(app.mag) max(app.mag)+app.moltM*max(app.mag)]);
                   hold(app.UIAxes,'on');
                   plot(app.UIAxes_2, app.time, app.phase, 'r');
                   axis(app.UIAxes_2,[0 app.time(end) min(app.phase)-abs(app.moltP*min(app.phase)) max(app.phase)+abs(app.moltP*max(app.phase))]);
                   hold(app.UIAxes_2,'on');

                   %% Marker SemplesTime Vector Creation
                   app.aggregate();

               end
           
               % Plot marker
               [mValue , vIndex] = max(app.mag);
               plot(app.UIAxes,vIndex-1,mValue,'-o', 'MarkerFaceColor','r');
               text(app.UIAxes,vIndex-1,mValue,'      Max');
               
               [minValue , minIndex] = min(app.mag);
               plot(app.UIAxes,minIndex-1,minValue,'-o', 'MarkerFaceColor','r');
               text(app.UIAxes,minIndex-1,minValue,'      Min');
               

               % Highlights portion of axes between two markers
               % With alternate colours
               if(length(app.couples)>=2)
                   for k=1:(length(app.couples)-1)
                       if(mod(k,2)~=0)
                           patch(app.UIAxes, [app.couples(k) app.couples(k+1) app.couples(k+1) app.couples(k)], [[1 1]*min(app.mag)-10 [1 1]*max(app.mag)+10], [0.6 0.4 0.9], 'FaceAlpha',0.3, 'EdgeColor','none');
                       else
                           patch(app.UIAxes, [app.couples(k) app.couples(k+1) app.couples(k+1) app.couples(k)], [[1 1]*min(app.mag)-10 [1 1]*max(app.mag)+10], 'red', 'FaceAlpha',0.3, 'EdgeColor','none');
                       end
                   end
               end

           else
               %%Frequency Sweep
               %n = app.N_Cicli*app.N_Punti; %numero di punti totali
               %%Calcolate Mean Vector
%                if()
               if(length(app.mag) == app.N_Cicli*app.N_Punti)
               for i = 1:app.N_Punti
                   %x = zeros(1,str2num(app.N_Cicli));
                   %y = zeros(1,str2num(app.N_Cicli));
                   x = [];
                   y = [];
                   for j = 1:(app.N_Cicli)
                       %x(j) = app.mag(i+(app.N_Punti*(j-1)));
                       %y(j) = app.phase(i+(app.N_Punti*(j-1)));
                       x = [x  app.mag(i+(app.N_Punti*(j-1)))];
                       y = [y  app.phase(i+(app.N_Punti*(j-1)))];
                   end
                   a = mean(x); %media del modulo
                   e = mean(y); %media della fase
                   c = std(x); %deviazione standard modulo
                   d = std(y); %deviazione standard fase
                   
                   app.mean_mag = [app.mean_mag a];
                   app.mean_phase = [app.mean_phase e];
                   app.std_mag = [app.std_mag c];
                   app.std_phase = [app.std_phase d];
                   
               end
               app.create_mean_table();
               %Plot Means Vectors

                 %HO TESTATO LA FUNZIONE DA MATLAB MA NON SONO SICURO SE
                 %PUÒ ESSERE USATA NELL'APP
               semilogx(app.UIAxes,app.freq_1,app.mean_mag,'-o','MarkerFaceColor','k');         
               semilogx(app.UIAxes_2,app.freq_1,app.mean_phase,'-o','MarkerFaceColor','r');
               hold(app.UIAxes,'on');
               hold(app.UIAxes_2,'on');
               semilogx(app.UIAxes,app.freq_1,(app.mean_mag-app.std_mag),'b');          
               semilogx(app.UIAxes_2,app.freq_1,(app.mean_phase-app.std_phase),'g');
               hold(app.UIAxes,'on');
               hold(app.UIAxes_2,'on');
               semilogx(app.UIAxes,app.freq_1,(app.mean_mag+app.std_mag),'b');          
               semilogx(app.UIAxes_2,app.freq_1,(app.mean_phase+app.std_phase),'g');
               else
                   app.ReceiveddataTextArea.Value= "Error in received data!";
               end


           end


       end

       function create_mean_table(app)
           MeanMagnitude=transpose(app.mean_mag);
           Frequency=transpose(app.freq(1:app.N_Punti));
           MeanPhase=transpose(app.mean_phase);
           Sample=transpose(app.time(1:app.N_Punti));
           StdMagnitude=transpose(app.std_mag);
           StdPhase=transpose(app.std_phase);
           app.data_Meantable=table(Sample,Frequency,MeanMagnitude,StdMagnitude,MeanPhase,StdPhase);
       end


       %% Couples the user markers in a vector storing only the Samples times
       function aggregate(app)

           for i=1:app.marker_saved(end).N
               if(app.marker_saved(i).Lable ~= "Peak")
                   app.couples = [app.couples app.marker_saved(i).Sample];
               end
           end
       end


       %% Start Operation, two cases:
       %% Serial: Send via UART a start command 
       %% Bluetooth: write on car2 a start command
       function start_operations(app)
           %%Serial
           app.clear_to_send = "true";
           app.cont_usedata = 0;
           %Cheks type of connection
           if(strcmpi(app.Switch.Value,'Serial')==1)


               if((app.con_state("left",false)== "Connected"))
                   try
                       stato = app.SweepSwitch.Value;

                       if strcmpi(stato,'Off')
                           app.freqa = app.FrequencyEditField.Value;
                           input_check(app);
                           start1= "start "+app.freqa;
                       else
                           %Takes and Cheks input data
                           app.freq1 = app.StartFrequencyEditField_2.Value;
                           app.freq2 = app.StopFrequencyEditField.Value;                         
                           app.nPunti = app.SweepPointsEditField.Value;                         
                           app.nCicli = app.SweepCyclesEditField.Value;
                           input_check(app);
                           start1 = "start2 "+app.freq1+" "+app.freq2+" "+app.nPunti+" "+app.nCicli;
                           %app.N_Punti = str2num(app.SweepPointsEditField.Value);
                           %app.N_Cicli = str2num(app.SweepCyclesEditField.Value);
                           app.N_Punti = str2double(app.SweepPointsEditField.Value);
                           app.N_Cicli = str2double(app.SweepCyclesEditField.Value);
                       end

                       %sends only if inputs are acceptable
                       if(app.clear_to_send == "true")
                           app.first_time = 0;
                           app.update_freq=0;
                           fprintf(app.current_port, start1, "async");
                           % Set use_data() as the function called whenever there are bytes available
                           app.current_port.BytesAvailableFcn = {@app.use_data};
                       end
                       send_ok = true;
                   catch
                       send_ok = false;

                   end
                   if (send_ok)
                       app.ReceiveddataTextArea.Value="Start sent successfully!";
                       app.AddMarkerButton.Enable = 'on';
                       app.StopButton.Enable = 'on';
                       app.MarkernameButton.Enable = 'on';
                       app.FrequencySettingsPanel.Enable = 'off';
                       app.ClearButton.Enable = 'on';
                   end
               end

           else

               %%Bluetooth   %Da valutare come fare il check di connesso e
               %%disconnesso come avviene per la seriale!
               send_ok = false;
               stato = app.SweepSwitch.Value;

               if strcmpi(stato,'Off')
                   app.freqa = app.FrequencyEditField.Value;
                   input_check(app);
                   len_freqA = strlength(app.freqa);
                   num_spaces = (14-len_freqA); %Number of spaces to include in the command in order to send exactly 20 characters
                   spaces_string = blanks(num_spaces);
                   start1= "start "+ app.freqa + spaces_string;
                   app.first_time = 0;
                   app.update_freq=0;
               else
                   app.freq1 = app.StartFrequencyEditField_2.Value;
                   app.freq2 = app.StopFrequencyEditField.Value;
                   app.nPunti = app.SweepPointsEditField.Value;
                   app.nCicli = app.SweepCyclesEditField.Value;
                   input_check(app);
                   start_temp = app.freq1+" "+app.freq2+" "+app.nPunti+" "+app.nCicli;
                   len_temp = strlength(start_temp);
                   num_spaces = (20 - len_temp);
                   spaces_string = blanks(num_spaces);
                   start1 = start_temp + spaces_string;
                   %app.N_Punti = str2num(app.SweepPointsEditField.Value);
                   %app.N_Cicli = str2num(app.SweepCyclesEditField.Value);
                   app.N_Punti = str2double(app.SweepPointsEditField.Value);
                   app.N_Cicli = str2double(app.SweepCyclesEditField.Value);
                   app.first_time = 0;
                   app.update_freq=0;
               end
               try
                   %sends only if inputs are acceptable
                   if(app.clear_to_send == "true")
                       write(app.car2,start1);
                       send_ok = true;
                   end

               catch
                   send_ok = false;
               end
               if (send_ok)
                   app.ReceiveddataTextArea.Value="Start sent successfully!";
                   app.AddMarkerButton.Enable = 'on';
                   app.StopButton.Enable = 'on';
                   app.MarkernameButton.Enable = 'on';
                   app.FrequencySettingsPanel.Enable = 'off';
                   app.ClearButton.Enable = 'on';
                   

               end

           end
       end

       
       %% Input check function
       function input_check(app)
           if strcmpi(app.SweepSwitch.Value,'Off')

               % single frequency
               if((str2double(app.freqa))<100 || (str2double(app.freqa))>150000 || (ischar(str2double(app.freqa))))
                   app.ReceiveddataTextArea.Value = "Incorrect Frequency input";
                   app.clear_to_send = "false";
               end
           else
               % frequency sweep
               if(str2double(app.freq1)<100 || str2double(app.freq1)>150000 || ischar(str2double(app.freq1)))
                   app.ReceiveddataTextArea.Value = "Incorrect Start Frequency input";
                   app.clear_to_send = "false";
               end
               if(str2double(app.freq2)<100 || str2double(app.freq2)>150000 || ischar(str2double(app.freq2)))
                   app.ReceiveddataTextArea.Value = "Incorrect Stop Frequency input";
                   app.clear_to_send = "false";
               end
               if(str2double(app.nPunti)<0 || str2double(app.nPunti)>100 || ischar(str2double(app.nPunti)))
                   app.ReceiveddataTextArea.Value = "Incorrect Sweep Points input";
                   app.clear_to_send = "false";
               end
               if(str2double(app.nCicli)<1 || str2double(app.nCicli)>200|| ischar(str2double(app.nCicli)))
                   app.ReceiveddataTextArea.Value = "Incorrect Sweep Cycles input";
                   app.clear_to_send = "false";
               end

           end
       end
           
       
       %% Stop Operation, two cases:
       %% Serial: Send via UART a stop command 
       %% Bluetooth: write on car2 a stop command
       function stop_operations(app)

           %Cheks type of connection
           if(strcmpi(app.Switch.Value,'Serial')==1)

               %%Serial
               %send_ok = false;
               if((app.con_state("left",false)== "Connected"))
                   try
                       fprintf(app.current_port,"stop", "async");
                       send_ok = true;
                   catch
                       send_ok = false;
                   end
                   if (send_ok)
                       app.update_freq=0;
                       app.ReceiveddataTextArea.Value="Stop sent successfully!";
                       app.PlotFullDataButton.Enable = 'on';
                       app.SavedataButton.Enable = 'on';
                       app.AddMarkerButton.Enable = 'off';
                       app.FrequencySettingsPanel.Enable = 'on';
                       app.ResetButton.Enable = 'on';

                   end
               end

           else
               %%Bluetooth   %Da valutare come fare il check di connesso e
               %%disconnesso come avviene per la seriale!
               %send_ok = false;
               try
                   write(app.car2,"stop                ");
                   send_ok = true;
               catch
                   send_ok = false;
               end
               if (send_ok)
                   app.update_freq=0;
                   app.ReceiveddataTextArea.Value="Stop sent successfully!";
                   app.PlotFullDataButton.Enable = 'on';
                   app.SavedataButton.Enable = 'on';
                   app.AddMarkerButton.Enable = 'off';
                   app.FrequencySettingsPanel.Enable = 'on';
                   app.ResetButton.Enable = 'on';

               end
           end

       end


       %% Scans for BLE devices
       function scan_ble(app)
           %Scan BLE devices
           ble_info = blelist;
           Lista = ble_info.Name(~cellfun('isempty',ble_info.Name));     
           app.BluetoothDevicesDropDown.Items =  Lista;
       end
       
       
       %% BLE Connection function
       %% Connect to the device, create characteristics and specify DataAvailableFcn
       function connect_ble(app)


           %Fuction assigned to DataAvailableFcn of the characteristic
           %Store datas and calls process_data()
           function Ble_UseData(src,evt)
               %% questo probabilmente va prima di entrare nella funzione process_data perché non viene cancellato bene il grafico

               [data_ble, ~] = read(app.car, 'oldest'); %in timestamp dovrebbe andarci l'orario della trasmissione
               fc_ble = uint8(data_ble(2:5));
               mag_ble = uint8(data_ble(6:9));
               ph_ble =  uint8(data_ble(10:13));
               app.data_freq = typecast( fc_ble, 'single');
               app.data_mag = typecast( mag_ble, 'single');
               app.data_phase = typecast( ph_ble, 'single');
               %                data = num2str(app.data_freq)+ " "+ num2str(app.data_mag)+ " "+num2str(app.data_phase);
               %                data = char(data);
               %
               if(mod(app.CountPunti,app.N_Punti) == 0)
                   cla(app.UIAxes);
                   cla(app.UIAxes_2);
                   app.mag_1 = [];
                   app.phase_1 = [];
                   app.freq_1 = [];
               end
%                if(app.cont_usedata ~=0)
                   app.process_data();
%                else
%                    app.cont_usedata = 1;
%                end

           end
           
           try
           app.b = ble(app.BluetoothDevicesDropDown.Value);
           app.car = characteristic(app.b,"00005301-0000-0041-4C50-574953450000","00005303-0000-0041-4C50-574953450000");
           app.car2 = characteristic(app.b,"00005301-0000-0041-4C50-574953450000","00005302-0000-0041-4C50-574953450000");
           subscribe(app.car,"notification");
           app.car.DataAvailableFcn = @Ble_UseData;
           app.StartButton.Enable = 'on';
           catch
                app.ReceiveddataTextArea.Value = "Connection failed";
           end
           
           app.ReceiveddataTextArea.Value = "Connected";
           
           app.time = [];
           app.mag = [];
           app.marker = [];
           app.phase = [];
           app.freq = [];
           app.mag_1 = [];
           app.phase_1 = [];
           app.freq_1 = [];
           app.count = 0;
           app.marker_saved = [];
           app.marker_saved(1).N = 0;
           app.marker_saved(1).Lable = " ";
           app.marker_saved(1).Freq = 0;
           app.marker_saved(1).Mag = 0;
           app.marker_saved(1).Phase = 0;
           app.marker_saved(1).Sample = 0;
           app.couples = [];
           app.CountPunti = 0;
           app.mean_mag = [];
           app.mean_phase = [];
           app.lable_cnt = 1; %try 1 or 0
           app.sample_period = 1;  % in this case Freq is fixed - take frq from the board
           app.current_time = []; % current time inizialize
       end
       

       %% BLE Disconnect function 
       function disconnect_ble(app)
           try
               app.b = "";
               clear app.b
               app.ReceiveddataTextArea.Value = "Disconnected";
           catch
               app.ReceiveddataTextArea.Value ="Disconnection failed";

           end
           
       end

       %% Process Data function
       %% This function process data received in both serial or bluetooth connection
       function process_data(app)
           
           % Update vectors with new value
           if isempty(app.time)
               app.time = [app.time 0];
           else
               app.time = [app.time (app.time(end) + app.sample_period)];
           end
           app.mag=[app.mag app.data_mag];
           app.phase=[app.phase app.data_phase];
           app.freq=[app.freq app.data_freq];
           app.mag_1=[app.mag_1 app.data_mag];
           app.phase_1=[app.phase_1 app.data_phase];
           app.freq_1=[app.freq_1 app.data_freq];           
           app.current_time_update(); % Update time Vector

           % Find lower and upper limits based on data received
           % (only for the first 50 samples the t1 is fixet to 0)
           if(app.time(end)+app.sample_period<(50*app.sample_period))
               t1=0;
           else
               t1= app.time(end)-(50*app.sample_period);
           end
           t2= app.time(end)+app.sample_period;
           
           %% Only at first run sets the Phase and Magnitude multiplier
           if(app.first_time==0)
                app.ReceiveddataTextArea.Value = "Measurement ON";
                app.set_multiplier();
           end
      
           % Sets axis limits
           app.set_limits();

           % Recognize Peaks and set a marker - stores magnitude and time
           % of the Peak and set count to 50 samples which will be used to fix the m2 value
            app.notice_peaks();


           % Overrides m2 or m1 value to keep the Peak on Screen
           %if app.count>0
  
%                     app.m2= real_peak+app.moltM*real_peak;
%                     app.m1= app.data_mag-app.moltM*min(app.mag);
                    %app.m2=max(app.mag(end-50:50))+2;
                   % app.m1=min(app.mag(end-50:50))-2;
               

%                     app.m1=real_peak-app.moltM*real_peak;
%                     app.m2= app.data_mag+app.moltM*max(app.mag);


               app.count=app.count-app.sample_period;
           %end

           app.update_freq=app.update_freq+1;

           %% Update graphics elements every 5 samples      
           if (mod(app.update_freq,5)==0)
               % Plot data : 2 cases
               % 1 - Plot on logarithmic scale
               % 2 - Regular Plot with autoadjusted axes

               if length(app.time) > 1

                   if(strcmpi(app.SweepSwitch.Value,'Off')==1)
                       plot(app.UIAxes, app.time, app.mag,'k');
                       axis(app.UIAxes,[t1 t2 app.m1 app.m2]);  % dinamic adjust axis base on data
                       hold(app.UIAxes,'on');

                       plot(app.UIAxes_2, app.time, app.phase, 'r');
                       axis(app.UIAxes_2,[t1 t2 app.p1 app.p2]);  % dinamic adjust axis base on data
                       hold(app.UIAxes,'on');


                   else
                       semilogx(app.UIAxes,app.freq_1,app.mag_1,'o','MarkerFaceColor','k');
                       semilogx(app.UIAxes_2,app.freq_1,app.phase_1,'o','MarkerFaceColor','r');
                   end
               end
               
           end
           if (mod(app.update_freq,10)==0)
               app.create_data_table();
               app.UITable.Data = app.data_table;
               scroll(app.UITable, 'bottom'); % autoscroll Table
           end
           app.CountPunti = app.CountPunti + 1;
       end

       
       %% Reset Function
       %% sends a reset command to the Device
       function reset(app)
           %Cheks type of connection
           if(strcmpi(app.Switch.Value,'Serial')==1)

               %%Serial
               if((app.con_state("left",false)== "Connected"))

                   fprintf(app.current_port,"rst", "async");
                   app.ReceiveddataTextArea.Value="Reset sent successfully!";

               end

           else
               %%Bluetooth


%                write(app.car2,"rst                 ");
               app.ReceiveddataTextArea.Value="Reset sent successfully!";
               app.disconnect_ble();

           end

       end
       
       %% Sets only one time the multiplier for axis adjustment
       function set_multiplier(app)
               if(app.data_mag>0 && app.data_mag<100)
                   app.moltM = 0.05;
               elseif(app.data_mag>=100 && app.data_mag<1000)
                   app.moltM = 0.005;
               elseif(app.data_mag>=1000)
                   app.moltM = 0.0005;
               end
               if(abs(app.data_phase)>0 && abs(app.data_phase)<1)
                   app.moltP = 1;
               elseif(abs(app.data_phase)>=1 && abs(app.data_phase)<100)
                   app.moltP = 0.1;
               elseif(abs(app.data_phase)>=100 && abs(app.data_phase)<1000)
                   app.moltP = 0.01;
               end
               app.first_time = 1;
       end
       
       
      %% Sets limits for the axis
      function set_limits(app)
          if(app.time(end)>50)
            app.m2=max(app.mag(end-50:end))+2;
            app.m1=min(app.mag(end-50:end))-2;
            app.p2=max(app.phase(end-50:end))+10;
            app.p1=min(app.phase(end-50:end))-10;
          else
            app.m2=max(app.mag)+2;
            app.m1=min(app.mag)-2;
            app.p2=max(app.phase)+10;
            app.p1=min(app.phase)-10;
          end
%            app.p1= app.data_phase-abs(app.moltP*min(app.phase));
%            app.p2= app.data_phase+abs(app.moltP*max(app.phase));
           

      end

      %% Notice Peaks function
      function notice_peaks(app)
          app.marker = [app.marker " "];
          if(app.time(end)>50)
              if(app.data_mag >= (1.03*(mean(app.mag(end-50:end)))))


                  %               app.set_marker();
                  %               app.marker_saved(end).Lable = "Peak";
                  %               app.create_marker_table();
                  %               app.UITable2.Data = app.marker_table;

                  %               app.marker = [app.marker "Peak"];
                  app.count = 50*app.sample_period;
                  app.peek_saved=app.data_mag;
                  app.time_saved=app.time(end);
                  app.PeakType = 1;
                 % app.ReceiveddataTextArea.Value = "Picco maggiore";
              elseif  (app.data_mag < (0.97*(mean(app.mag(end-50:end)))))
                  app.count = 50*app.sample_period;
                  app.peek_saved=app.data_mag;
                  app.time_saved=app.time(end);
                  app.PeakType = 0;
                  %app.ReceiveddataTextArea.Value = "Picco minore";
              end
          else
              if(app.data_mag >= (1.03*(mean(app.mag)))) 
                  app.count = 50*app.sample_period;
                  app.peek_saved=app.data_mag;
                  app.time_saved=app.time(end);
                  app.PeakType = 1;
                  %app.ReceiveddataTextArea.Value = "Picco maggiore";

              elseif  (app.data_mag < (0.97*(mean(app.mag))))
                  app.count = 50*app.sample_period;
                  app.peek_saved=app.data_mag;
                  app.time_saved=app.time(end);
                  app.PeakType = 0;
                  %app.ReceiveddataTextArea.Value = "Picco minore";

              end

          end
      end

   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            
           % Baudrate drop-down
           app.BaudrateDropDown.Items = {'9600','38400','115200','230400'};
           app.BaudrateDropDown.ItemsData = {9600, 38400, 115200, 230400};
           
           
           % Update form
           app.form_upd();
           

        end

        % Close request function: Terminal
        function TerminalCloseRequest(app, event)
            % Clears all the varibles
            app.disconnect_fxn();
            app.time = [];
            app.mag = [];
            app.marker = [];
            app.marker_saved = [];
            app.phase = [];
            app.count =0;
            app.current_time = [];
            app.mag_1 = [];
            app.phase_1 = [];
            app.freq_1 = [];
            app.mean_mag = [];
            app.mean_phase = [];
            delete(app)

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %when button is pushed checks fisr if COM is set
            if(~isempty(app.PortDropDown.Value))
                switch app.con_state("left",false)
                    case "Connected"
                        app.disconnect_fxn();
                    case "Disconnected"
                        app.connect_fxn();
                end
            end
        end

        % Callback function: ClearButton, UIAxes
        function ClearButtonPushed(app, event)
            % Disconnects and clears plots, terminal and variables
%             value = app.SweepSwitch.Value;
%             if(strcmpi(value,'Off')==1)
%                 app.SingleFrequencyPanel.Enable = 'on';
%                 app.FrequencySweepPanel.Enable = 'off';
%             else
%                 app.SingleFrequencyPanel.Enable = 'off';
%                 app.FrequencySweepPanel.Enable = 'on';
%             end
            app.disconnect_fxn();
            app.disconnect_ble();
            delete(instrfind);
            app.time = [];
            app.mag = [];
            app.marker = [];
            app.phase = [];
            app.freq = [];
            app.marker_saved = [];
            app.data_table = [];
            app.marker_table = [];
            app.current_time = [];
            app.count =0;
            app.ReceiveddataTextArea.Value=' ';
            app.UITable.Data = {};
            app.UITable2.Data = {};
            app.SweepCyclesEditField.Value = ' ';
            app.SweepPointsEditField.Value = ' ';
            app.StopFrequencyEditField.Value = ' ';
            app.StartFrequencyEditField_2.Value = ' ';
            app.FrequencyEditField.Value = ' ';
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.Switch.Enable = 'on';
            app.WelcomeTab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.WelcomeTab;   
            app.SerialTab.Parent = [];
            app.BluetoothTab.Parent = [];
            
            
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            %calls save file function if app is disconnected 
                if  app.con_state("left",false) == "Disconnected" 
                    app.save_file()
                end
         
        end

        % Button pushed function: AddMarkerButton
        function AddMarkerButtonPushed(app, event)
            % Calls set_marker function
            app.set_marker();
            
        end

        % Button pushed function: MarkernameButton
        function MarkernameButtonPushed(app, event)
            % Calls add_lable function
            app.add_lable();
        end

        % Button pushed function: PlotFullDataButton
        function PlotFullDataButtonPushed(app, event)
            %% Highlights areas between the added markers
            app.full_data_plot();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.start_operations();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_operations();
        end

        % Value changed function: SweepSwitch
        function SweepSwitchValueChanged(app, event)
            value = app.SweepSwitch.Value;
            if(strcmpi(value,'Off')==1)
                app.SingleFrequencyPanel.Enable = 'on';
                app.FrequencySweepPanel.Enable = 'off';
                xlabel(app.UIAxes, 'Sample [n]')
                ylabel(app.UIAxes, 'Impedance Magnitude [\Omega]')
                xlabel(app.UIAxes_2, 'Sample [n]')
                ylabel(app.UIAxes_2, 'Impedance Phase [deg]')
                xtickformat(app.UIAxes_2,'%.f');
                xtickformat(app.UIAxes,'%.f');

            else
                app.SingleFrequencyPanel.Enable = 'off';
                app.FrequencySweepPanel.Enable = 'on';
                xlabel(app.UIAxes, 'Frequency [Hz]')
                ylabel(app.UIAxes, 'Impedance Magnitude [\Omega]')
                xlabel(app.UIAxes_2, 'Frequency [Hz]')
                ylabel(app.UIAxes_2, 'Impedance Phase [deg]')
                xtickformat(app.UIAxes_2,'%,.1f');
                xtickformat(app.UIAxes,'%,.1f');
            end
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.scan_ble();
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            app.connect_ble();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(strcmpi(value,'Serial')==1)
                app.SerialTab.Parent = app.TabGroup;
                app.TabGroup.SelectedTab = app.SerialTab;              
                app.BluetoothTab.Parent= [];
                app.WelcomeTab.Parent = [];
                
            else
                app.BluetoothTab.Parent= app.TabGroup;
                app.TabGroup.SelectedTab = app.BluetoothTab; 
                app.SerialTab.Parent = [];
                app.WelcomeTab.Parent = [];
                
                
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect_ble();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Terminal = uifigure('Visible', 'off');
            app.Terminal.Name = 'UI Figure';
            app.Terminal.CloseRequestFcn = createCallbackFcn(app, @TerminalCloseRequest, true);
            app.UIAxes = uiaxes(app.Terminal);
            title(app.UIAxes, 'Impedance Magnitude')
            xlabel(app.UIAxes, 'Sample [n]')
            ylabel(app.UIAxes, 'Impedance Magnitude [\Omega]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.UIAxes_2 = uiaxes(app.Terminal);
            title(app.UIAxes_2, 'Impedance Phase')
            xlabel(app.UIAxes_2, 'Sample [n]')
            ylabel(app.UIAxes_2, 'Impedance Phase [deg]')
            zlabel(app.UIAxes_2, 'Z')
            app.CommandsPanel = uipanel(app.Terminal);
            app.CommandsPanel.Title = 'Commands';
            app.PlotFullDataButton = uibutton(app.CommandsPanel, 'push');
            app.PlotFullDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFullDataButtonPushed, true);
            app.PlotFullDataButton.Text = 'Plot Full Data';
            app.StartButton = uibutton(app.CommandsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.CommandsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SavedataButton = uibutton(app.CommandsPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.Switch = uiswitch(app.CommandsPanel, 'slider');
            app.Switch.Items = {'Serial', 'Bluetooth'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Serial';
            app.ClearButton = uibutton(app.CommandsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.CommandsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MarkersPanel = uipanel(app.Terminal);
            app.MarkersPanel.Title = 'Markers';
            app.UITable2 = uitable(app.MarkersPanel);
            app.UITable2.ColumnName = {'Marker'; 'Lable'; 'Frequency'; 'Magnitude'; 'Phase'; 'Sample'};
            app.UITable2.RowName = {};
            app.EditField = uieditfield(app.MarkersPanel, 'text');
            app.MarkernameButton = uibutton(app.MarkersPanel, 'push');
            app.MarkernameButton.ButtonPushedFcn = createCallbackFcn(app, @MarkernameButtonPushed, true);
            app.MarkernameButton.Text = 'Marker name';
            app.AddMarkerButton = uibutton(app.MarkersPanel, 'push');
            app.AddMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @AddMarkerButtonPushed, true);
            app.AddMarkerButton.Text = 'Add Marker';
            app.ConsoleLogPanel = uipanel(app.Terminal);
            app.ConsoleLogPanel.Title = 'Console Log';
            app.ReceiveddataTextArea = uitextarea(app.ConsoleLogPanel);
            app.Insulinmeter20Label = uilabel(app.Terminal);
            app.Insulinmeter20Label.Text = 'Insulinmeter 2.0';
            app.UITable = uitable(app.Terminal);
            app.UITable.ColumnName = {'Sample'; 'Frequency'; 'Magnitude'; 'Phase'; 'Marker'; 'Timestamp'};
            app.UITable.RowName = {};
            app.FrequencySettingsPanel = uipanel(app.Terminal);
            app.FrequencySettingsPanel.Title = 'Frequency Settings';
            app.SingleFrequencyPanel = uipanel(app.FrequencySettingsPanel);
            app.SingleFrequencyPanel.Title = 'Single Frequency';
            app.FrequencyEditFieldLabel = uilabel(app.SingleFrequencyPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SingleFrequencyPanel, 'text');
            app.FrequencySweepPanel = uipanel(app.FrequencySettingsPanel);
            app.FrequencySweepPanel.Title = 'Frequency Sweep';
            app.StartFrequencyEditField_2Label = uilabel(app.FrequencySweepPanel);
            app.StartFrequencyEditField_2Label.Text = 'Start Frequency';
            app.StartFrequencyEditField_2 = uieditfield(app.FrequencySweepPanel, 'text');
            app.StopFrequencyEditFieldLabel = uilabel(app.FrequencySweepPanel);
            app.StopFrequencyEditFieldLabel.Text = 'Stop Frequency';
            app.StopFrequencyEditField = uieditfield(app.FrequencySweepPanel, 'text');
            app.SweepPointsEditFieldLabel = uilabel(app.FrequencySweepPanel);
            app.SweepPointsEditFieldLabel.Text = 'Sweep Points';
            app.SweepPointsEditField = uieditfield(app.FrequencySweepPanel, 'text');
            app.SweepCyclesEditFieldLabel = uilabel(app.FrequencySweepPanel);
            app.SweepCyclesEditFieldLabel.Text = 'Sweep Cycles';
            app.SweepCyclesEditField = uieditfield(app.FrequencySweepPanel, 'text');
            app.SweepSwitchLabel = uilabel(app.FrequencySettingsPanel);
            app.SweepSwitchLabel.Text = 'Sweep';
            app.SweepSwitch = uiswitch(app.FrequencySettingsPanel, 'slider');
            app.SweepSwitch.ValueChangedFcn = createCallbackFcn(app, @SweepSwitchValueChanged, true);
            app.TabGroup = uitabgroup(app.Terminal);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.WelcometoLabel = uilabel(app.WelcomeTab);
            app.WelcometoLabel.Text = 'Welcome to ';
            app.Insulinmeter20Label_2 = uilabel(app.WelcomeTab);
            app.Insulinmeter20Label_2.Text = 'Insulinmeter 2.0';
            app.Image = uiimage(app.WelcomeTab);
            app.Image.ImageSource = 'LogoFG.png';
            app.SerialTab = uitab(app.TabGroup);
            app.SerialTab.Title = 'Serial';
            app.SerialConnectionPanel = uipanel(app.SerialTab);
            app.SerialConnectionPanel.Title = 'Serial Connection';
            app.PortLabel = uilabel(app.SerialConnectionPanel);
            app.PortLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.SerialConnectionPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.SerialConnectionPanel);
            app.BaudrateDropDownLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.SerialConnectionPanel);
            app.BaudrateDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.BaudrateDropDown.Value = '';
            app.ConnectButton = uibutton(app.SerialConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.BluetoothTab = uitab(app.TabGroup);
            app.BluetoothTab.Title = 'Bluetooth';
            app.BluetoothConnectionPanel = uipanel(app.BluetoothTab);
            app.BluetoothConnectionPanel.Title = 'Bluetooth Connection';
            app.BluetoothDevicesDropDownLabel = uilabel(app.BluetoothConnectionPanel);
            app.BluetoothDevicesDropDownLabel.Text = 'Bluetooth Devices';
            app.BluetoothDevicesDropDown = uidropdown(app.BluetoothConnectionPanel);
            app.ScanButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ConnectButton_2 = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.DisconnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.FrancOlinoTeamLabel = uilabel(app.Terminal);
            app.FrancOlinoTeamLabel.Text = 'FrancOlino Team';
            % Show the figure after all components are created
            app.Terminal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_BIA_F

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Terminal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Terminal)
        end
    end
end