classdef serial_terminal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Terminal                        matlab.ui.Figure
        TerminalPanel                   matlab.ui.container.Panel
        SendPanel                       matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        SendButton                      matlab.ui.control.Button
        ServerstatusPanel               matlab.ui.container.Panel
        ClientconnectionstatusTextArea  matlab.ui.control.TextArea
        ClientconnectionstatusTextAreaLabel  matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        BaudrateDropDown                matlab.ui.control.DropDown
        BaudrateDropDownLabel           matlab.ui.control.Label
        PortDropDown                    matlab.ui.control.DropDown
        PortLabel                       matlab.ui.control.Label
        SentdataTextArea                matlab.ui.control.TextArea
        SentdataTextAreaLabel           matlab.ui.control.Label
        ReceiveddataTextArea            matlab.ui.control.TextArea
        ReceiveddataTextAreaLabel       matlab.ui.control.Label
    end

   properties (Access = private)
        %timers
        generic_upd_tmr = timer;
        %data
        port_list;
        connection_log = {'Terminal started'};
        sent_log;
        rsvd_log;
        %vars
        current_port = '';
        %sem
        port_fxn_pass = false;
        con_in_process = false;
    end 


    methods (Access = private)
        
        function  form_upd(app)
            switch app.con_state("left",false)
                case 'Connected'
                     if(app.current_port.BytesAvailable)
                       app.rsvd_log{end+1} = fscanf(app.current_port); 
                       app.rsvd_log{end} = app.rsvd_log{end}(1:end-1);
                       app.ReceiveddataTextArea.Value = app.rsvd_log;
                     end
                case 'Disconnected'
                   %portlist 
                   s_info = instrhwinfo('serial');
                   app.port_list =  s_info.AvailableSerialPorts;
                   %gui
                   app.PortDropDown.Items = app.port_list;
                   app.PortDropDown.ItemsData = app.port_list;
            end
        end
        
function connect_fxn(app)
           %% port connection module
            try
                app.con_in_process=true;
                app.current_port = serial(app.PortDropDown.Value);
                fopen(app.current_port);
                app.port_fxn_pass = true;
            catch ME
                if  (ME.identifier == "MATLAB:serial:fopen:opfailed")
                    warning('MATLAB_serial_fopen_opfailed');                   
                else
                     warning('Connection_failen_unknown_reason');
                end 
                app.port_fxn_pass=false;
                app.connection_log{end+1} = [app.PortDropDown.Value,' connect failed'];
            end
                app.con_in_process = false;
                
            %% end of port connection module
            
            if (app.con_state("left",false) == "Connected")
                set(app.current_port,'BaudRate',app.BaudrateDropDown.Value);
                app.ConnectButton.Text = 'Disconnect';
                app.PortDropDown.Enable = 'off';
                app.BaudrateDropDown.Enable = 'off';
                app.connection_log{end+1} = [app.PortDropDown.Value,' ',...
                    num2str(app.BaudrateDropDown.Value),...
                    ' Started'];
                app.SendButton.Enable = 'on';
            end
            disp (app.connection_log);
            app.connection_log{end+1}= 377093;
            app.ClientconnectionstatusTextArea.Value = app.connection_log;
        end
        
        function disconnect_fxn(app)
            if  app.con_state("left",false) == "Connected"
                app.ConnectButton.Text = 'connect';
                app.con_state("set",false);
                try
                    fclose(app.current_port);
                    app.connection_log{end+1} = [app.PortDropDown.Value,' ','disconnected'];
                catch 
                    warning('Disconnect_failed');
                    app.connection_log{end+1} = [app.PortDropDown.Value,' Disconnect failed'];
                end
                app.current_port = '';
                app.PortDropDown.Enable = 'on';
                app.BaudrateDropDown.Enable = 'on';
                app.SendButton.Enable = 'off';
                app.connection_log{end+1}= 377093;
                app.ClientconnectionstatusTextArea.Value = app.connection_log;
            end
        end
        
        function state = con_state(app,set,val)
            state = "Disconnected";
            if (app.port_fxn_pass && ~app.con_in_process)
                state = "Connected";
            end
            if (app.port_fxn_pass && app.con_in_process)
                state = "Connecting";
            end
            if (set == "set")
                app.port_fxn_pass = val;
                app.con_in_process = val;
            end
      end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %create gui upd tmer
           app.generic_upd_tmr.Period = 0.1;
           app.generic_upd_tmr.ExecutionMode = 'fixedRate';
           app.generic_upd_tmr.TimerFcn = @(~, ~)app.form_upd;
           
           %baudrate drop-down
           app.BaudrateDropDown.Items = {'9600','38400','115200'};
           app.BaudrateDropDown.ItemsData = {9600, 38400, 115200};
           
           %update form
           app.form_upd();
           
           start(app.generic_upd_tmr);
        end

        % Close request function: Terminal
        function TerminalCloseRequest(app, event)
            app.disconnect_fxn();
            delete(app)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(~isempty(app.PortDropDown.Value))
                switch app.con_state("left",false)
                    case "Connected"
                        app.disconnect_fxn();
                    case "Disconnected"
                     app.connect_fxn();
                end
            end
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            send_ok = false;
            if((app.con_state("left",false)== "Connected") && (~isempty(app.EditField.Value)))
                try
                    fprintf(app.current_port,app.EditField.Value);
                     send_ok = true;
                catch 
                    warning('Send_failed');
                    app.connection_log{end+1}= 377093;
                    app.ClientconnectionstatusTextArea.Value = app.connection_log;
                    send_ok = false;
                end
            end
            
            app.ClientconnectionstatusTextArea.Value = app.connection_log;
            
            if (send_ok)
                app.sent_log{end+1}=app.EditField.Value;
                app.SentdataTextArea.Value=app.sent_log;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Terminal = uifigure('Visible', 'off');
            app.Terminal.Name = 'UI Figure';
            app.Terminal.CloseRequestFcn = createCallbackFcn(app, @TerminalCloseRequest, true);
            app.TerminalPanel = uipanel(app.Terminal);
            app.TerminalPanel.Title = 'Terminal';
            app.ReceiveddataTextAreaLabel = uilabel(app.TerminalPanel);
            app.ReceiveddataTextAreaLabel.Text = 'Received data';
            app.ReceiveddataTextArea = uitextarea(app.TerminalPanel);
            app.SentdataTextAreaLabel = uilabel(app.TerminalPanel);
            app.SentdataTextAreaLabel.Text = 'Sent data';
            app.SentdataTextArea = uitextarea(app.TerminalPanel);
            app.ServerstatusPanel = uipanel(app.TerminalPanel);
            app.ServerstatusPanel.Title = 'Server status';
            app.PortLabel = uilabel(app.ServerstatusPanel);
            app.PortLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ServerstatusPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.ServerstatusPanel);
            app.BaudrateDropDownLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.ServerstatusPanel);
            app.BaudrateDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.BaudrateDropDown.Value = '';
            app.ConnectButton = uibutton(app.ServerstatusPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ClientconnectionstatusTextAreaLabel = uilabel(app.ServerstatusPanel);
            app.ClientconnectionstatusTextAreaLabel.Text = 'Client connection status';
            app.ClientconnectionstatusTextArea = uitextarea(app.ServerstatusPanel);
            app.SendPanel = uipanel(app.TerminalPanel);
            app.SendPanel.Title = 'Send';
            app.SendButton = uibutton(app.SendPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.EditField = uieditfield(app.SendPanel, 'text');
            % Show the figure after all components are created
            app.Terminal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = serial_terminal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Terminal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Terminal)
        end
    end
end